classdef ShearBending < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        fbd                             matlab.ui.control.UIAxes
        shear                           matlab.ui.control.UIAxes
        moment                          matlab.ui.control.UIAxes
        PlotButton                      matlab.ui.control.Button
        lengthmLabel                    matlab.ui.control.Label
        length                          matlab.ui.control.NumericEditField
        PointLoadsPanel                 matlab.ui.container.Panel
        downveLabel                     matlab.ui.control.Label
        NumberofpointloadsEditFieldLabel  matlab.ui.control.Label
        point_num                       matlab.ui.control.NumericEditField
        valueslefttorightkNLabel        matlab.ui.control.Label
        point_val                       matlab.ui.control.EditField
        PositionlefttorightmLabel       matlab.ui.control.Label
        point_pos                       matlab.ui.control.EditField
        UniformlyDistributedLoadsPanel  matlab.ui.container.Panel
        downveLabel_2                   matlab.ui.control.Label
        NumberofUniformlydistributedloadsEditFieldLabel  matlab.ui.control.Label
        udl_num                         matlab.ui.control.NumericEditField
        valuesfromlefttorightkNmLabel   matlab.ui.control.Label
        udl_val                         matlab.ui.control.EditField
        frompositionleftmostmLabel      matlab.ui.control.Label
        udl_from                        matlab.ui.control.EditField
        endingpositionrightmostmLabel   matlab.ui.control.Label
        udl_till                        matlab.ui.control.EditField
        MomentsPanel                    matlab.ui.container.Panel
        CCveLabel                       matlab.ui.control.Label
        NumberofMomentsEditFieldLabel   matlab.ui.control.Label
        mom_num                         matlab.ui.control.NumericEditField
        valueslefttorightkNmLabel       matlab.ui.control.Label
        mom_val                         matlab.ui.control.EditField
        positionslefttorightmLabel      matlab.ui.control.Label
        mom_pos                         matlab.ui.control.EditField
        ShearforceandBendingmomentdiagramforasimplysupportedbeamLabel  matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
% This script will take input from the user about the beam and 
% shows its bending moment and shear force diagram
% This script is only for simply supported beam

%input part
%clc; clearvars;


%disp('We are dealing with a simply supported beam with supports at its ends')
%disp(' ')
%len = input('Enter the length of beam in meters : ');
len = app.length.Value ;
%disp(' ');

%disp('Enter the loads from left to right ');
%disp(' ');

%point loads
%num = input('Enter the number of point loads : ');
%disp(' ');
num = app.point_num.Value;

if num > 0
    point_loads = zeros(1,num);
    pos_point = zeros(1,num);
    
    str_point_val = app.point_val.Value;
    point_loads = str2double(strsplit(str_point_val,','));
    
    str_point_pos = app.point_pos.Value;
    pos_point = str2double(strsplit(str_point_pos,','));


    %{
    for i = 1:num
    
        %x = ['Enter the values of load ', num2str(i), ' in kN : '];
        
        point_loads(1,i) = input(x);
        
    
        x = ['Enter the position of load ', num2str(i), ' (from left end) in meters : '];
        pos_point(1,i) = input(x);
        disp(' ');
    
        while pos_point(1,i) > len || pos_point(1,i) < 0 
            disp('Invalid load position');
            fprintf('Enter the value between 0 and %d \n',len);
            disp(' ');
            pos_point(1,i) = input('Enter the position of load (from left end) in meters : ');
            disp(' ');
        end
    end
    %}
end


% uniformly distributed load
%u_num = input('Enter number of uniformly distributed loads : ');
%disp(' ');
u_num = app.udl_num.Value;

if u_num > 0
    
    u_load = zeros(1,u_num);
    u_pos = zeros(2,u_num);
    % first row for starting point second row for ending point
    
    str_udl_val = app.udl_val.Value;
    u_load = str2double(strsplit(str_udl_val,','));
    
    str_udl_from = app.udl_from.Value;
    u_from = str2double(strsplit(str_udl_from,','));
    
    str_udl_till = app.udl_till.Value;
    u_till = str2double(strsplit(str_udl_till,','));
    
    u_pos = [u_from ; u_till];


    %{
    for i = 1:u_num
        
        u_load(1, i) = input('Enter the value of load per unit length in kN/m : ');
        u_pos(1, i) = input('Enter the starting point of force from left end in m : ');
        % retake value if invlid value is entered
        while u_pos(1, i) < 0 || u_pos(1, i) > len
            disp(' ');
            str = ['Enter a valid value between 0 and ' num2str(len) ' '];
            disp(str);
            disp(' ');
            u_pos(1, i) = input('Enter the starting point of force from left end in m : ');
        end
            
        u_pos(2, i) = input('Enter the ending point of force from left end in m : ');
        % retake value if invalid input is entered
        while u_pos(2, i) < u_pos(1, i) || u_pos(2, i) > len
            disp(' ');
            str = ['Enter valid value between ' num2str(u_pos(1,i)) ' and ' num2str(len) ' '];
            disp(str);
            disp(' ');
            u_pos(2, i) = input('Enter the ending point of force from left end in m : ');
        end
        disp(' ');
    end
    %}
    
end

% additional moments

%m_num = input('Enter the number of moments on beam : ');
%disp(' ');
m_num = app.mom_num.Value;


m_val = zeros(1, m_num);
m_pos = zeros(1, m_num);

str_mom_val = app.mom_val.Value;
m_val = str2double(strsplit(str_mom_val,','));

str_mom_pos = app.mom_pos.Value;
m_pos = str2double(strsplit(str_mom_pos,','));

%{
for i = 1:m_num
    str = ['Enter value of moment ' num2str(i) ' : '];
    m_val(1, i) = input(str);
    str = ['Enter the position of moment ' num2str(i) ' from left end : '];
    m_pos(1, i) = input(str);
    disp(' ');
    
    
    while m_pos(1, i) < 0 || m_pos(1, i) > len
        str = ['Enter a valid value between 0 and ' len];
        disp(str);
        disp(' ');
        
        str = ['Enter the position of moment ' num2str(i) ' from left end : '];
        m_pos(1, i) = input(str);
        disp(' ');
    end
end
%}       
        
% calculating part
x = 0:1/1000:len;

% calculate support forces
a_y = 0;
b_y = 0;

% point loads
for i = 1:num
    
    a_y = a_y + point_loads(1,i)*(len - pos_point(1,i));
    b_y = b_y + point_loads(1,i)*(pos_point(1,i));
    
end

% uniformly distributed forces
for i = 1:u_num
    
    a_y = a_y + u_load(1, i)*(u_pos(2, i) - u_pos(1, i))*(len - (u_pos(2, i) + u_pos(1, i))/2);
    b_y = b_y + u_load(1, i)*(u_pos(2, i) - u_pos(1, i))*((u_pos(2, i) + u_pos(1, i))/2);
    
end

% additional moments
for i = 1:m_num
    
    a_y = a_y + m_val(1, i);
    b_y = b_y + m_val(1, i);
    
end

a_y = a_y/len ;
b_y = b_y/len ;

str = ['Reaction forces are :' newline 'A_y = ' num2str(a_y) 'kN' newline 'B_y = ' num2str(b_y) 'kN'];
disp(str);
%fprintf("Reaction forces are : \n A_y = %.2d kN \n B_y = %.2d kN \n", a_y, b_y);

% calculate 'V' shear force and 'M'

V = -1*a_y + 0*x;
M = a_y*x;

% point loads
for i = 1:num
    % Using Macaulay's method for point loads
    V = V + (x > pos_point(1,i))*point_loads(1,i);
    M = M - (x > pos_point(1,i)).*(point_loads(1,i).*(x - pos_point(1,i)));
end

% u_loads
for i = 1:u_num
     
    V = V + ((x > u_pos(1, i)) & (x < u_pos(2, i))).*u_load(i).*(x - u_pos(1, i)) + (x >= u_pos(2, i))*u_load(i)*(u_pos(2,i) - u_pos(1,i));
    M = M - ((x > u_pos(1, i)) & (x < u_pos(2, i))).*u_load(i).*((x - u_pos(1, i)).^(2) /2) - (x >= u_pos(2, i)).*u_load(i).*(u_pos(2,i) - u_pos(1,i)).*(x - (u_pos(2, i) + u_pos(1, i))/2); 

end

% additional moments
for i = 1:m_num
    M = M - (x >= m_pos(1, i))*m_val(1, i);
end

%plotting diagram

plot(app.fbd, 0:1/1000:len, zeros(1,len*1000 + 1),'Color' , 'k', 'LineWidth', 2);
hold(app.fbd, 'on');
axis(app.fbd, [-1 len+1 -2 2]);

% arrows for a_y and b_y
quiver(app.fbd, 0, -1, 0, 1, 1, 'Color', 'b', 'Linewidth', 1);     % a_y
str = [num2str(a_y) ' kN'];
text(app.fbd, 0, -1.2, str, 'HorizontalAlignment', 'center');

str = [num2str(b_y) ' kN'];
text(app.fbd,len, -1.2, str, 'HorizontalAlignment', 'center'); 
quiver(app.fbd,len, -1, 0, 1, 1, 'Color', 'b', 'Linewidth', 1);     % b_y

% arrows for point loads
for i = 1:num
    if point_loads(1, i) >= 0 
        quiver(app.fbd, pos_point(1, i), 1.3, 0, -1.3, 1, 'Color', 'r', 'Linewidth', 1);     % down arrow
        str = [num2str(point_loads(1, i)) ' kN'];
        text(app.fbd, pos_point(1, i), 1.5, str, 'HorizontalAlignment', 'center');
        
    else
        quiver(app.fbd, pos_point(1, i), -1.3, 0, 1.3, 1, 'Color', 'r', 'Linewidth', 1);    % up arrow
        str = [num2str(point_loads(1, i)) ' kN'];
        text(app.fbd, pos_point(1, i), -1.5, str, 'HorizontalAlignment', 'center');
    end
end

% arrows for uniformly distributed loads
for i = 1:u_num
    for j = u_pos(1, i):0.25:u_pos(2, i)
        if u_load(1, i) >= 0
            quiver(app.fbd, j, 1, 0, -1, 1, 'Color', 'b', 'Linewidth', 1);     % down arrows
        else
            quiver(app.fbd, j,-1, 0, 1, 1, 'Color', 'b', 'Linewidth', 1);       % up arrows
        end
    end
    
    if u_load(1, i) >= 0   % joining line and text
        line(app.fbd,[u_pos(1, i) u_pos(2, i)], [1 1], 'Color', 'b');
        str = [num2str(u_load(1,i)) ' kN/m'];
        text(app.fbd, (u_pos(1,i) + u_pos(2,i))/2, 1.2, str, 'HorizontalAlignment', 'center');
    else
        line(app.fbd, [u_pos(1, i) u_pos(2, i)], [-1 -1], 'Color', 'b');
        str = [num2str(u_load(1,i)) 'kN/m'];
        text(app.fbd, (u_pos(1,i) + u_pos(2,i))/2, -1.2, str, 'HorizontalAlignment', 'center');
    end
end

% stars for additional moments

for i = 1:m_num
    plot(app.fbd, m_pos(1, i), 0, '*g');
    str = [num2str(m_val(1, i)) 'kN-m'];
    text(app.fbd, m_pos(1, i), 0.5, str, 'HorizontalAlignment', 'center');
end

%             down arrow  //////////////
%quiver([x], [y], [0], [-1], 0.5);

%             up arrow    ///////////////
%quiver([x], [y], [0], [1], 0.5);


hold(app.fbd, 'off');

%plotting shear force diagram

plot(app.shear ,x,V);
title(app.shear, 'Shear force');
hold(app.shear, 'on');
grid(app.shear, 'on');

v_points = zeros(1, num + 2*u_num + m_num);
m_points = zeros(1, num + 2*u_num + m_num);
pos = zeros(1, num + 2*u_num + m_num);

for i = 1:(num + 2*u_num + m_num)
    if i <= num
        v_points(1, i) = V(1, 1+pos_point(1, i)*1000);
        m_points(1, i) = M(1, 1+pos_point(1, i)*1000);
        pos(1, i) = pos_point(1, i);
    elseif i<= num + u_num
        v_points(1 ,i) = V(1, 1+u_pos(1, i - num)*1000);
        m_points(1, i) = M(1, 1+u_pos(1, i - num)*1000);
        pos(1, i) = u_pos(1, i - num);
    elseif i<= num + 2*u_num
        v_points(1, i) = V(1, 1+u_pos(2, i - num - u_num)*1000);
        m_points(1, i) = M(1, 1+u_pos(2, i - num - u_num)*1000);
        pos(1, i) = u_pos(2, i - num - u_num);
    else 
        v_points(1, i) = V(1, 1+m_pos(1, i - num - 2*u_num)*1000);
        m_points(1, i) = M(1, 1+m_pos(1, i - num - 2*u_num)*1000);
        pos(1, i) = m_pos(1, i - num - 2*u_num);
    end
        
end


plot(app.shear, pos, v_points,'or','MarkerFaceColor','y');
line(app.shear, [0 len], [0 0], 'Color', 'k','LineWidth',1);  % 'x' axis
line(app.shear, [0, 0], [0, V(1,1)]); % starting line of shear force
line(app.shear, [len, len], [0, V(1,len*1000)]); % ending line of shear force
% fill(x, V, 'b');
v_max = max(V);
v_min = min(V);
axis(app.shear, [-1 len+1 v_min*(13/10) v_max*(13/10)]);
hold(app.shear, 'off');


% plotting bending moment diagram

plot(app.moment,x,M);
grid(app.moment, 'on');
title(app.moment, 'Bending Moment');
hold(app.moment, 'on');
line(app.moment, [0 len], [0 0], 'Color', 'k','LineWidth',1);
% fill(x, M, 'b');
plot(app.moment, pos, m_points, 'or', 'MarkerFaceColor','y');
line(app.moment, [0 len], [0 0], 'Color', 'k','LineWidth',1); % axis
m_max = max(M);
m_min = min(M);
axis(app.moment, [-1 len+1 m_min*(13/10) m_max*(13/10)]);
hold(app.moment, 'off');


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.fbd = uiaxes(app.UIFigure);
            xlabel(app.fbd, 'X')
            ylabel(app.fbd, 'Y')
            app.shear = uiaxes(app.UIFigure);
            title(app.shear, 'Shear Force')
            xlabel(app.shear, 'X (m)')
            ylabel(app.shear, 'V (kN)')
            app.moment = uiaxes(app.UIFigure);
            title(app.moment, 'Bending Moment')
            xlabel(app.moment, 'X (m)')
            ylabel(app.moment, 'M (kN-m)')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.lengthmLabel = uilabel(app.UIFigure);
            app.lengthmLabel.Text = 'length (m)';
            app.length = uieditfield(app.UIFigure, 'numeric');
            app.PointLoadsPanel = uipanel(app.UIFigure);
            app.PointLoadsPanel.Title = 'Point Loads';
            app.downveLabel = uilabel(app.PointLoadsPanel);
            app.downveLabel.Text = 'down (+ve)';
            app.NumberofpointloadsEditFieldLabel = uilabel(app.PointLoadsPanel);
            app.NumberofpointloadsEditFieldLabel.Text = 'Number of point loads';
            app.point_num = uieditfield(app.PointLoadsPanel, 'numeric');
            app.valueslefttorightkNLabel = uilabel(app.PointLoadsPanel);
            app.valueslefttorightkNLabel.Text = 'values (left to right) (kN)';
            app.point_val = uieditfield(app.PointLoadsPanel, 'text');
            app.point_val.Value = 'eg : "1,2,3"';
            app.PositionlefttorightmLabel = uilabel(app.PointLoadsPanel);
            app.PositionlefttorightmLabel.Text = 'Position (left to right) (m)  ';
            app.point_pos = uieditfield(app.PointLoadsPanel, 'text');
            app.point_pos.Value = 'eg : "1,2,3"';
            app.UniformlyDistributedLoadsPanel = uipanel(app.UIFigure);
            app.UniformlyDistributedLoadsPanel.Title = 'Uniformly Distributed Loads';
            app.downveLabel_2 = uilabel(app.UniformlyDistributedLoadsPanel);
            app.downveLabel_2.Text = 'down (+ve)';
            app.NumberofUniformlydistributedloadsEditFieldLabel = uilabel(app.UniformlyDistributedLoadsPanel);
            app.NumberofUniformlydistributedloadsEditFieldLabel.Text = 'Number of Uniformly distributed loads';
            app.udl_num = uieditfield(app.UniformlyDistributedLoadsPanel, 'numeric');
            app.valuesfromlefttorightkNmLabel = uilabel(app.UniformlyDistributedLoadsPanel);
            app.valuesfromlefttorightkNmLabel.Text = 'values (from left to right) (kN/m)';
            app.udl_val = uieditfield(app.UniformlyDistributedLoadsPanel, 'text');
            app.udl_val.Value = 'eg : "1,2,3"';
            app.frompositionleftmostmLabel = uilabel(app.UniformlyDistributedLoadsPanel);
            app.frompositionleftmostmLabel.Text = 'from position (left most) (m)';
            app.udl_from = uieditfield(app.UniformlyDistributedLoadsPanel, 'text');
            app.udl_from.Value = 'eg : "1,2,3"';
            app.endingpositionrightmostmLabel = uilabel(app.UniformlyDistributedLoadsPanel);
            app.endingpositionrightmostmLabel.Text = 'ending position (right most) (m)';
            app.udl_till = uieditfield(app.UniformlyDistributedLoadsPanel, 'text');
            app.udl_till.Value = 'eg : "1,2,3"';
            app.MomentsPanel = uipanel(app.UIFigure);
            app.MomentsPanel.Title = 'Moments';
            app.CCveLabel = uilabel(app.MomentsPanel);
            app.CCveLabel.Text = 'C.C (+ve)';
            app.NumberofMomentsEditFieldLabel = uilabel(app.MomentsPanel);
            app.NumberofMomentsEditFieldLabel.Text = 'Number of Moments';
            app.mom_num = uieditfield(app.MomentsPanel, 'numeric');
            app.valueslefttorightkNmLabel = uilabel(app.MomentsPanel);
            app.valueslefttorightkNmLabel.Text = 'values (left to right) (kN-m)';
            app.mom_val = uieditfield(app.MomentsPanel, 'text');
            app.mom_val.Value = 'eg : "1,2,3"';
            app.positionslefttorightmLabel = uilabel(app.MomentsPanel);
            app.positionslefttorightmLabel.Text = 'positions (left to right) (m)';
            app.mom_pos = uieditfield(app.MomentsPanel, 'text');
            app.mom_pos.Value = 'eg : "1,2,3"';
            app.ShearforceandBendingmomentdiagramforasimplysupportedbeamLabel = uilabel(app.UIFigure);
            app.ShearforceandBendingmomentdiagramforasimplysupportedbeamLabel.Text = 'Shear force and Bending moment diagram for a simply supported beam';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ShearBending

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end